classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        ProyectoFinalAnlisisNumrico20232MelissaJuanEstebanQuinteroLabel  matlab.ui.control.Label
        BiseccionTab                    matlab.ui.container.Tab
        TableBiseccion                  matlab.ui.control.Table
        CalcularBiseccion               matlab.ui.control.Button
        xsBiseccion                     matlab.ui.control.EditField
        xsEditFieldLabel                matlab.ui.control.Label
        xiBiseccion                     matlab.ui.control.EditField
        xiEditFieldLabel                matlab.ui.control.Label
        CriterioParadaBiseccion         matlab.ui.control.EditField
        CriteriodeparadaEditFieldLabel  matlab.ui.control.Label
        FuncionBiseccion                matlab.ui.control.EditField
        FuncinEditFieldLabel            matlab.ui.control.Label
        GraficaBiseccion                matlab.ui.control.UIAxes
        NewtonRaphsonTab                matlab.ui.container.Tab
        CriterioParadaNewtonRaphson     matlab.ui.control.EditField
        CriteriodeparadaEditFieldLabel_2  matlab.ui.control.Label
        XoNewtonRaphson                 matlab.ui.control.EditField
        XoEditFieldLabel                matlab.ui.control.Label
        FuncionNewtonRaphson            matlab.ui.control.EditField
        FuncinEditField_2Label          matlab.ui.control.Label
        CalcularNewtonRaphson           matlab.ui.control.Button
        TableNewtonRaphson              matlab.ui.control.Table
        GraficaNewtonRaphson            matlab.ui.control.UIAxes
        SistemasEcuacionesLinealesTab   matlab.ui.container.Tab
        TableSistemasEcuacionesLineales  matlab.ui.control.Table
        ToleranciaSistemasEcuacionesLineales  matlab.ui.control.EditField
        CantidadIteracionesLabel        matlab.ui.control.Label
        XoSistemasEcuacionesLineales    matlab.ui.control.EditField
        XoEditField_2Label              matlab.ui.control.Label
        ValoresdeBSistemasEcuacionesLineales  matlab.ui.control.EditField
        ValoresdeBEditFieldLabel        matlab.ui.control.Label
        ValoresdeASistemasEcuacionesLineales  matlab.ui.control.EditField
        ValoresdeAEditFieldLabel        matlab.ui.control.Label
        CalcularSistemasEcuacionesLineales  matlab.ui.control.Button
        InterpolacionNewtonTab          matlab.ui.container.Tab
        TableResultadoInterpolacionNewton  matlab.ui.control.Table
        polinomioInterpolacionNewton    matlab.ui.control.EditField
        PolinomioLabel                  matlab.ui.control.Label
        yiInterpolacionNewton           matlab.ui.control.EditField
        yiEditFieldLabel                matlab.ui.control.Label
        CalcularInterpolacionNewton     matlab.ui.control.Button
        xiInterpolacionNewton           matlab.ui.control.EditField
        xiEditFieldLabel_2              matlab.ui.control.Label
        GraficaInterpolacionNewton      matlab.ui.control.UIAxes
        AjusteCuadrticoTab              matlab.ui.container.Tab
        ResultadoAjusteCuadratico       matlab.ui.control.Label
        ValoresdexAjusteCuadratico      matlab.ui.control.EditField
        ValoresdexEditFieldLabel        matlab.ui.control.Label
        ValoresdeyAjusteCuadratico      matlab.ui.control.EditField
        ValoresdeyEditFieldLabel        matlab.ui.control.Label
        TableAjusteCuadratico           matlab.ui.control.Table
        CalcularAjusteCuadratico        matlab.ui.control.Button
        GraficaAjusteCuadratico         matlab.ui.control.UIAxes
        DerivadasCentradasTab           matlab.ui.container.Tab
        TableDerivadasCentradas         matlab.ui.control.Table
        HDerivadasCentradas             matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        xDerivadasCentradas             matlab.ui.control.EditField
        xEditFieldLabel_2               matlab.ui.control.Label
        FuncionDerivadasCentradas       matlab.ui.control.EditField
        FuncionEditFieldLabel_2         matlab.ui.control.Label
        CalcularDerivadasCentradas      matlab.ui.control.Button
        GraficaDerivadasCentradas       matlab.ui.control.UIAxes
        Simpson38Tab                    matlab.ui.container.Tab
        ResultadoSimpson38              matlab.ui.control.Label
        nSimpson38                      matlab.ui.control.EditField
        nLabel                          matlab.ui.control.Label
        bSimpson38                      matlab.ui.control.EditField
        bEditFieldLabel                 matlab.ui.control.Label
        aSimpson38                      matlab.ui.control.EditField
        aEditFieldLabel                 matlab.ui.control.Label
        FuncionSimpson38                matlab.ui.control.EditField
        FuncinEditFieldLabel_2          matlab.ui.control.Label
        CalcularSimpson38               matlab.ui.control.Button
        GraficaSimpson38                matlab.ui.control.UIAxes
        RungeKutta3Tab                  matlab.ui.container.Tab
        xRungeKutta3                    matlab.ui.control.EditField
        xEditFieldLabel                 matlab.ui.control.Label
        hRungeKutta3                    matlab.ui.control.EditField
        hEditField_2Label               matlab.ui.control.Label
        nRungeKutta3                    matlab.ui.control.EditField
        nEditFieldLabel                 matlab.ui.control.Label
        yRungeKutta3                    matlab.ui.control.EditField
        yEditFieldLabel                 matlab.ui.control.Label
        FuncionRungeKutta3              matlab.ui.control.EditField
        FuncionEditFieldLabel           matlab.ui.control.Label
        CalcularRungeKutta3             matlab.ui.control.Button
        TableRungeKutta3                matlab.ui.control.Table
        GraficaRungeKutta3              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularBiseccion
        function CalcularBiseccionButtonPushed(app, event)
            app.GraficaBiseccion.NextPlot = "replaceall";
            app.TableBiseccion.ColumnFormat = {'long','long','long','long','long','long','long','long'};

            % Obtener datos de la interfaz
            xi = app.xiBiseccion.Value;
            xs = app.xsBiseccion.Value;
            funcionMatematica = app.FuncionBiseccion.Value;
            criterioParada = app.CriterioParadaBiseccion.Value;
            
            % Llamar a la función de bisección
            [M2, x, A, B, f] = funcionBiseccion(funcionMatematica,xi,xs,criterioParada);
    
            % Actualizar la tabla en la interfaz
            app.TableBiseccion.Data = M2;
    
            % Graficar la función y los puntos encontrados
            fplot(app.GraficaBiseccion, f, [min(x), max(x)], '-'); % Graficar la función
            hold(app.GraficaBiseccion, 'on');
            plot(app.GraficaBiseccion, A, B, 'r*'); % Marcar los puntos
            line(app.GraficaBiseccion, [min(x), max(x)], [0 0], 'Color', 'k', 'LineStyle', '--'); % Marcar el intervalo inicial
            hold(app.GraficaBiseccion, 'off');
        end

        % Button pushed function: CalcularNewtonRaphson
        function CalcularNewtonRaphsonButtonPushed(app, event)
            funcion = app.FuncionNewtonRaphson.Value;
            xi = app.XoNewtonRaphson.Value;
            tolerancia = app.CriterioParadaNewtonRaphson.Value;
            
            [x_solucion, M] = funcionNewtonRaphson(funcion, xi, tolerancia);
            
            % Actualizar la tabla de resultados
            app.TableNewtonRaphson.Data = M;
            
            % Gráfico en el componente GraficaNewtonRaphson (axes)
            f = str2func(['@(x)' funcion]);
            x_vals = linspace(min(x_solucion) - 1, max(x_solucion) + 1, 100);
            y_vals = f(x_vals);
            
            plot(app.GraficaNewtonRaphson, x_vals, y_vals, 'DisplayName', 'Función');
            hold(app.GraficaNewtonRaphson, 'on');
            scatter(app.GraficaNewtonRaphson, x_solucion, f(x_solucion), 'o', 'DisplayName', 'Iteraciones');
            title(app.GraficaNewtonRaphson, 'Método de Newton-Raphson');
            xlabel(app.GraficaNewtonRaphson, 'x');
            ylabel(app.GraficaNewtonRaphson, 'f(x)');
            legend(app.GraficaNewtonRaphson);
            grid(app.GraficaNewtonRaphson, 'on');
            hold(app.GraficaNewtonRaphson, 'off');
        end

        % Button pushed function: CalcularSistemasEcuacionesLineales
        function CalcularSistemasEcuacionesLinealesButtonPushed(app, event)
            
            % Leer y convertir los valores de A, b, x0 y tolerancia
            A = str2num(app.ValoresdeASistemasEcuacionesLineales.Value);
            b = str2num(app.ValoresdeBSistemasEcuacionesLineales.Value);
            x0 = str2num(app.XoSistemasEcuacionesLineales.Value);
            tolerancia = str2double(app.ToleranciaSistemasEcuacionesLineales.Value);
            tolerancia = round(tolerancia);
            
            % Llamar a la función de resolución de sistemas de ecuaciones
            [iteraciones, errores] = funcionSistemasEcuacionesLineales(A, b, x0, tolerancia);
            
            % Determinar el número de variables (columnas)
            numVariables = size(A, 2);
            
            % Crear nombres para las columnas, incluyendo la columna de iteraciones
            nombresColumnas = cell(1, 1 + numVariables * 2);
            nombresColumnas{1} = 'Iteración';
            for i = 1:numVariables
                nombresColumnas{1 + i} = ['Variable ', num2str(i)];
                nombresColumnas{1 + numVariables + i} = ['Error Variable ', num2str(i)];
            end
            
            % Configurar los nombres de las columnas en la tabla
            app.TableSistemasEcuacionesLineales.ColumnName = nombresColumnas;
            
            % Agregar el número de iteración a los datos de la tabla
            numIteraciones = size(iteraciones, 1);
            numerosIteracion = (1:numIteraciones)';
            
            % Mostrar los resultados en la tabla, incluyendo el número de iteración
            app.TableSistemasEcuacionesLineales.Data = [numerosIteracion, iteraciones, errores];

        end

        % Button pushed function: CalcularInterpolacionNewton
        function CalcularInterpolacionNewtonButtonPushed(app, event)
            % Obtener los valores de xi y yi
            xi = str2num(app.xiInterpolacionNewton.Value);
            yi = str2num(app.yiInterpolacionNewton.Value);

            syms x;
            
            % Llamar a la función de interpolación de Newton
            [polinomio, tablaDiferencias] = funcionInterpolacionNewton(xi, yi);
            
            % Mostrar la tabla de diferencias divididas
            app.TableResultadoInterpolacionNewton.Data = tablaDiferencias;
            
            % Mostrar el polinomio en el campo de texto
            app.polinomioInterpolacionNewton.Value = char(polinomio);
            
            % Crear un rango de valores para evaluar el polinomio
            x_range = linspace(min(xi), max(xi), 100);
            y_range = double(subs(polinomio, x, x_range));
            
            % Graficar la interpolación y los puntos originales
            plot(app.GraficaInterpolacionNewton, xi, yi, 'bo', 'DisplayName', 'Puntos Originales'); % Puntos originales
            hold(app.GraficaInterpolacionNewton, 'on');
            plot(app.GraficaInterpolacionNewton, x_range, y_range, 'r-', 'DisplayName', 'Interpolación de Newton'); % Curva de interpolación
            hold(app.GraficaInterpolacionNewton, 'off');
            legend(app.GraficaInterpolacionNewton, 'show');


        end

        % Button pushed function: CalcularAjusteCuadratico
        function CalcularAjusteCuadraticoButtonPushed(app, event)
            x = str2num(app.ValoresdexAjusteCuadratico.Value); % Valores de x
            y = str2num(app.ValoresdeyAjusteCuadratico.Value); % Valores de y
            
            % Realizar el ajuste cuadrático
            [coeficientes, X, Y, X2, X3, X4, XY, X2Y, Y_calculado, Error] = funcionAjusteCuadratico(x, y);
            
            % Actualizar la tabla con los datos calculados
            app.TableAjusteCuadratico.Data = table(X, Y, X2, X3, X4, XY, X2Y, Y_calculado, Error);
            
            % Mostrar la gráfica
            plot(app.GraficaAjusteCuadratico, X, Y, 'bo'); % Datos originales
            hold(app.GraficaAjusteCuadratico, 'on');
            plot(app.GraficaAjusteCuadratico, X, Y_calculado, 'r-'); % Ajuste cuadrático
            hold(app.GraficaAjusteCuadratico, 'off');
            
            % Actualizar el label con la ecuación del ajuste cuadrático en el orden deseado
            app.ResultadoAjusteCuadratico.Text = sprintf('y = %.4f + %.4fx + %.4fx^2', coeficientes(3), coeficientes(2), coeficientes(1));
        end

        % Button pushed function: CalcularDerivadasCentradas
        function CalcularDerivadasCentradasButtonPushed(app, event)
            fString = app.FuncionDerivadasCentradas.Value; % Función como string
            x = str2double(app.xDerivadasCentradas.Value); % Valor de x
            h = str2double(app.HDerivadasCentradas.Value); % Valor de h
            
            % Convertir la cadena de la función a un handle de función
            f = str2func(['@(x)', fString]);
            
            % Verificar que h no sea cero o muy pequeño para evitar errores numéricos
            if h <= 0
                app.TableDerivadasCentradas.Data = {'H debe ser mayor que cero', '', ''};
                return;
            end
            
            % Definir un rango de valores alrededor de x para calcular las derivadas
            x_range = linspace(x - 10*h, x + 10*h, 100);
            
            % Inicializar matrices para almacenar los valores de las derivadas
            firstDeriv_values = zeros(size(x_range));
            secondDeriv_values = zeros(size(x_range));
            thirdDeriv_values = zeros(size(x_range));
            
            % Calcular derivadas para cada punto en el rango
            for i = 1:length(x_range)
                [firstDeriv_values(i), secondDeriv_values(i), thirdDeriv_values(i)] = funcionDerivadasCentradas(f, x_range(i), h);
            end
            
            % Graficar las derivadas
            plot(app.GraficaDerivadasCentradas, x_range, firstDeriv_values, 'r', 'DisplayName', 'Primera Derivada');
            hold(app.GraficaDerivadasCentradas, 'on');
            plot(app.GraficaDerivadasCentradas, x_range, secondDeriv_values, 'g', 'DisplayName', 'Segunda Derivada');
            plot(app.GraficaDerivadasCentradas, x_range, thirdDeriv_values, 'b', 'DisplayName', 'Tercera Derivada');
            hold(app.GraficaDerivadasCentradas, 'off');
            legend(app.GraficaDerivadasCentradas, 'show');
            
            % Actualizar la tabla con los resultados en el punto x
            [firstDeriv, secondDeriv, thirdDeriv] = funcionDerivadasCentradas(f, x, h);
            app.TableDerivadasCentradas.Data = {firstDeriv; secondDeriv; thirdDeriv};

        end

        % Button pushed function: CalcularSimpson38
        function CalcularSimpson38ButtonPushed(app, event)
            % Convertir la cadena de la función a un handle de función
            f = str2func(['@(x)', app.FuncionSimpson38.Value]);
            a = str2double(app.aSimpson38.Value);
            b = str2double(app.bSimpson38.Value);
            n = str2double(app.nSimpson38.Value);
            
            
            % Calcular la integral usando Simpson 3/8
            resultado = funcionSimpson38(f, a, b, n);
            app.ResultadoSimpson38.Text = ['Resultado Integral: ', num2str(resultado)];
            
            % Definir un rango de valores para graficar la función
            x_range = linspace(a, b, 1000);
            y_range = arrayfun(f, x_range);
            
            % Graficar la función
            plot(app.GraficaSimpson38, x_range, y_range, 'b-', 'DisplayName', 'Función');
            hold(app.GraficaSimpson38, 'on');
            
            % Opcional: Ilustrar el área bajo la curva
            x_fill = linspace(a, b, n+1);
            y_fill = arrayfun(f, x_fill);
            fill(app.GraficaSimpson38, [a, x_fill, b], [0, y_fill, 0], [0.53, 0.81, 0.92], 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            
            hold(app.GraficaSimpson38, 'off');
            legend(app.GraficaSimpson38, 'show');
        end

        % Button pushed function: CalcularRungeKutta3
        function CalcularRungeKutta3ButtonPushed(app, event)
           app.TableRungeKutta3.ColumnFormat = {'long','long','long','long','long','long','long','long','long'};
            x = convertStringsToChars(app.xRungeKutta3.Value);
            y = convertStringsToChars(app.yRungeKutta3.Value);
            h = convertStringsToChars(app.hRungeKutta3.Value);
            n = convertStringsToChars(app.nRungeKutta3.Value);
            funcionMatematica = convertStringsToChars(app.FuncionRungeKutta3.Value);
            [M,M3,M4]= funcionRungeKutta3(funcionMatematica,x,y,h,n);
            app.TableRungeKutta3.Data =M;

            % Ajustar según el formato específico de M si es necesario
            x_valores = M(:, 1);
            y_valores = M(:, 2); 
            
            % Graficar los resultados
            plot(app.GraficaRungeKutta3, M3, M4, 'b-', 'DisplayName', 'Runge-Kutta Orden 3');
            legend(app.GraficaRungeKutta3, 'show');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.GridLayout = uigridlayout(app.InicioTab);
            app.ProyectoFinalAnlisisNumrico20232MelissaJuanEstebanQuinteroLabel = uilabel(app.GridLayout);
            app.ProyectoFinalAnlisisNumrico20232MelissaJuanEstebanQuinteroLabel.Text = {'Proyecto Final'; 'Análisis Numérico '; '2023-2'; ''; ''; 'Karen Melissa Ardila Marin'; 'Juan Esteban Quintero Rodriguez'; 'Sebastian Delgado Cardenas'; 'Juan Esteban Granda Rodriguez'; ''};
            app.BiseccionTab = uitab(app.TabGroup);
            app.BiseccionTab.Title = 'Biseccion';
            app.GraficaBiseccion = uiaxes(app.BiseccionTab);
            title(app.GraficaBiseccion, 'Grafica Bisección')
            xlabel(app.GraficaBiseccion, 'X')
            ylabel(app.GraficaBiseccion, 'Y')
            zlabel(app.GraficaBiseccion, 'Z')
            app.FuncinEditFieldLabel = uilabel(app.BiseccionTab);
            app.FuncinEditFieldLabel.Text = 'Función';
            app.FuncionBiseccion = uieditfield(app.BiseccionTab, 'text');
            app.CriteriodeparadaEditFieldLabel = uilabel(app.BiseccionTab);
            app.CriteriodeparadaEditFieldLabel.Text = 'Criterio de parada';
            app.CriterioParadaBiseccion = uieditfield(app.BiseccionTab, 'text');
            app.xiEditFieldLabel = uilabel(app.BiseccionTab);
            app.xiEditFieldLabel.Text = 'xi';
            app.xiBiseccion = uieditfield(app.BiseccionTab, 'text');
            app.xsEditFieldLabel = uilabel(app.BiseccionTab);
            app.xsEditFieldLabel.Text = 'xs';
            app.xsBiseccion = uieditfield(app.BiseccionTab, 'text');
            app.CalcularBiseccion = uibutton(app.BiseccionTab, 'push');
            app.CalcularBiseccion.ButtonPushedFcn = createCallbackFcn(app, @CalcularBiseccionButtonPushed, true);
            app.CalcularBiseccion.Text = 'Calcular';
            app.TableBiseccion = uitable(app.BiseccionTab);
            app.TableBiseccion.ColumnName = {'Iteración'; 'xi'; 'xr'; 'xs'; 'f(xi)'; 'f(xr)'; 'f(xs)'; 'ErrorN'};
            app.TableBiseccion.RowName = {};
            app.NewtonRaphsonTab = uitab(app.TabGroup);
            app.NewtonRaphsonTab.Title = 'Newton-Raphson';
            app.GraficaNewtonRaphson = uiaxes(app.NewtonRaphsonTab);
            title(app.GraficaNewtonRaphson, 'Gráfica Newton-Raphson')
            xlabel(app.GraficaNewtonRaphson, 'X')
            ylabel(app.GraficaNewtonRaphson, 'Y')
            zlabel(app.GraficaNewtonRaphson, 'Z')
            app.TableNewtonRaphson = uitable(app.NewtonRaphsonTab);
            app.TableNewtonRaphson.ColumnName = {'Iteración'; 'xi'; 'f(xi)'; 'f''(xi)'; 'xi+1'; 'Error Normalizado'};
            app.TableNewtonRaphson.RowName = {};
            app.CalcularNewtonRaphson = uibutton(app.NewtonRaphsonTab, 'push');
            app.CalcularNewtonRaphson.ButtonPushedFcn = createCallbackFcn(app, @CalcularNewtonRaphsonButtonPushed, true);
            app.CalcularNewtonRaphson.Text = 'Calcular';
            app.FuncinEditField_2Label = uilabel(app.NewtonRaphsonTab);
            app.FuncinEditField_2Label.Text = 'Función';
            app.FuncionNewtonRaphson = uieditfield(app.NewtonRaphsonTab, 'text');
            app.XoEditFieldLabel = uilabel(app.NewtonRaphsonTab);
            app.XoEditFieldLabel.Text = 'Xo';
            app.XoNewtonRaphson = uieditfield(app.NewtonRaphsonTab, 'text');
            app.CriteriodeparadaEditFieldLabel_2 = uilabel(app.NewtonRaphsonTab);
            app.CriteriodeparadaEditFieldLabel_2.Text = 'Criterio de parada';
            app.CriterioParadaNewtonRaphson = uieditfield(app.NewtonRaphsonTab, 'text');
            app.SistemasEcuacionesLinealesTab = uitab(app.TabGroup);
            app.SistemasEcuacionesLinealesTab.Title = 'Sistemas Ecuaciones Lineales';
            app.CalcularSistemasEcuacionesLineales = uibutton(app.SistemasEcuacionesLinealesTab, 'push');
            app.CalcularSistemasEcuacionesLineales.ButtonPushedFcn = createCallbackFcn(app, @CalcularSistemasEcuacionesLinealesButtonPushed, true);
            app.CalcularSistemasEcuacionesLineales.Text = 'Calcular';
            app.ValoresdeAEditFieldLabel = uilabel(app.SistemasEcuacionesLinealesTab);
            app.ValoresdeAEditFieldLabel.Text = 'Valores de A';
            app.ValoresdeASistemasEcuacionesLineales = uieditfield(app.SistemasEcuacionesLinealesTab, 'text');
            app.ValoresdeBEditFieldLabel = uilabel(app.SistemasEcuacionesLinealesTab);
            app.ValoresdeBEditFieldLabel.Text = 'Valores de B';
            app.ValoresdeBSistemasEcuacionesLineales = uieditfield(app.SistemasEcuacionesLinealesTab, 'text');
            app.XoEditField_2Label = uilabel(app.SistemasEcuacionesLinealesTab);
            app.XoEditField_2Label.Text = 'Xo';
            app.XoSistemasEcuacionesLineales = uieditfield(app.SistemasEcuacionesLinealesTab, 'text');
            app.CantidadIteracionesLabel = uilabel(app.SistemasEcuacionesLinealesTab);
            app.CantidadIteracionesLabel.Text = 'Cantidad Iteraciones';
            app.ToleranciaSistemasEcuacionesLineales = uieditfield(app.SistemasEcuacionesLinealesTab, 'text');
            app.TableSistemasEcuacionesLineales = uitable(app.SistemasEcuacionesLinealesTab);
            app.TableSistemasEcuacionesLineales.ColumnName = {'Resultado'};
            app.TableSistemasEcuacionesLineales.RowName = {};
            app.InterpolacionNewtonTab = uitab(app.TabGroup);
            app.InterpolacionNewtonTab.Title = 'Interpolacion Newton';
            app.GraficaInterpolacionNewton = uiaxes(app.InterpolacionNewtonTab);
            title(app.GraficaInterpolacionNewton, 'Interpolacion de Newton')
            xlabel(app.GraficaInterpolacionNewton, 'X')
            ylabel(app.GraficaInterpolacionNewton, 'Y')
            zlabel(app.GraficaInterpolacionNewton, 'Z')
            app.xiEditFieldLabel_2 = uilabel(app.InterpolacionNewtonTab);
            app.xiEditFieldLabel_2.Text = 'xi';
            app.xiInterpolacionNewton = uieditfield(app.InterpolacionNewtonTab, 'text');
            app.CalcularInterpolacionNewton = uibutton(app.InterpolacionNewtonTab, 'push');
            app.CalcularInterpolacionNewton.ButtonPushedFcn = createCallbackFcn(app, @CalcularInterpolacionNewtonButtonPushed, true);
            app.CalcularInterpolacionNewton.Text = 'Calcular';
            app.yiEditFieldLabel = uilabel(app.InterpolacionNewtonTab);
            app.yiEditFieldLabel.Text = 'yi';
            app.yiInterpolacionNewton = uieditfield(app.InterpolacionNewtonTab, 'text');
            app.PolinomioLabel = uilabel(app.InterpolacionNewtonTab);
            app.PolinomioLabel.Text = 'Polinomio';
            app.polinomioInterpolacionNewton = uieditfield(app.InterpolacionNewtonTab, 'text');
            app.TableResultadoInterpolacionNewton = uitable(app.InterpolacionNewtonTab);
            app.TableResultadoInterpolacionNewton.ColumnName = {'Resultado'};
            app.TableResultadoInterpolacionNewton.RowName = {};
            app.AjusteCuadrticoTab = uitab(app.TabGroup);
            app.AjusteCuadrticoTab.Title = 'Ajuste Cuadrático';
            app.GraficaAjusteCuadratico = uiaxes(app.AjusteCuadrticoTab);
            title(app.GraficaAjusteCuadratico, 'Gráfica Ajuste Cuadrático')
            xlabel(app.GraficaAjusteCuadratico, 'X')
            ylabel(app.GraficaAjusteCuadratico, 'Y')
            zlabel(app.GraficaAjusteCuadratico, 'Z')
            app.CalcularAjusteCuadratico = uibutton(app.AjusteCuadrticoTab, 'push');
            app.CalcularAjusteCuadratico.ButtonPushedFcn = createCallbackFcn(app, @CalcularAjusteCuadraticoButtonPushed, true);
            app.CalcularAjusteCuadratico.Text = 'Calcular';
            app.TableAjusteCuadratico = uitable(app.AjusteCuadrticoTab);
            app.TableAjusteCuadratico.ColumnName = {'x'; 'y'; 'x^2'; 'x^3'; 'x^4'; 'xy'; 'x^2y'; 'y^*'; 'e'};
            app.TableAjusteCuadratico.RowName = {};
            app.ValoresdeyEditFieldLabel = uilabel(app.AjusteCuadrticoTab);
            app.ValoresdeyEditFieldLabel.Text = 'Valores de y';
            app.ValoresdeyAjusteCuadratico = uieditfield(app.AjusteCuadrticoTab, 'text');
            app.ValoresdexEditFieldLabel = uilabel(app.AjusteCuadrticoTab);
            app.ValoresdexEditFieldLabel.Text = 'Valores de x';
            app.ValoresdexAjusteCuadratico = uieditfield(app.AjusteCuadrticoTab, 'text');
            app.ResultadoAjusteCuadratico = uilabel(app.AjusteCuadrticoTab);
            app.ResultadoAjusteCuadratico.Text = 'Resultado Ajuste: ';
            app.DerivadasCentradasTab = uitab(app.TabGroup);
            app.DerivadasCentradasTab.Title = 'Derivadas Centradas';
            app.GraficaDerivadasCentradas = uiaxes(app.DerivadasCentradasTab);
            title(app.GraficaDerivadasCentradas, 'Derivadas Centradas')
            xlabel(app.GraficaDerivadasCentradas, 'X')
            ylabel(app.GraficaDerivadasCentradas, 'Y')
            zlabel(app.GraficaDerivadasCentradas, 'Z')
            app.CalcularDerivadasCentradas = uibutton(app.DerivadasCentradasTab, 'push');
            app.CalcularDerivadasCentradas.ButtonPushedFcn = createCallbackFcn(app, @CalcularDerivadasCentradasButtonPushed, true);
            app.CalcularDerivadasCentradas.Text = 'Calcular';
            app.FuncionEditFieldLabel_2 = uilabel(app.DerivadasCentradasTab);
            app.FuncionEditFieldLabel_2.Text = 'Funcion';
            app.FuncionDerivadasCentradas = uieditfield(app.DerivadasCentradasTab, 'text');
            app.xEditFieldLabel_2 = uilabel(app.DerivadasCentradasTab);
            app.xEditFieldLabel_2.Text = 'x';
            app.xDerivadasCentradas = uieditfield(app.DerivadasCentradasTab, 'text');
            app.hEditFieldLabel = uilabel(app.DerivadasCentradasTab);
            app.hEditFieldLabel.Text = 'h';
            app.HDerivadasCentradas = uieditfield(app.DerivadasCentradasTab, 'text');
            app.TableDerivadasCentradas = uitable(app.DerivadasCentradasTab);
            app.TableDerivadasCentradas.ColumnName = {'Resultado'};
            app.TableDerivadasCentradas.RowName = {};
            app.Simpson38Tab = uitab(app.TabGroup);
            app.Simpson38Tab.Title = 'Simpson 3/8';
            app.GraficaSimpson38 = uiaxes(app.Simpson38Tab);
            title(app.GraficaSimpson38, 'Simpson 3/8')
            xlabel(app.GraficaSimpson38, 'X')
            ylabel(app.GraficaSimpson38, 'Y')
            zlabel(app.GraficaSimpson38, 'Z')
            app.CalcularSimpson38 = uibutton(app.Simpson38Tab, 'push');
            app.CalcularSimpson38.ButtonPushedFcn = createCallbackFcn(app, @CalcularSimpson38ButtonPushed, true);
            app.CalcularSimpson38.Text = 'Calcular';
            app.FuncinEditFieldLabel_2 = uilabel(app.Simpson38Tab);
            app.FuncinEditFieldLabel_2.Text = 'Función';
            app.FuncionSimpson38 = uieditfield(app.Simpson38Tab, 'text');
            app.aEditFieldLabel = uilabel(app.Simpson38Tab);
            app.aEditFieldLabel.Text = 'a';
            app.aSimpson38 = uieditfield(app.Simpson38Tab, 'text');
            app.bEditFieldLabel = uilabel(app.Simpson38Tab);
            app.bEditFieldLabel.Text = 'b';
            app.bSimpson38 = uieditfield(app.Simpson38Tab, 'text');
            app.nLabel = uilabel(app.Simpson38Tab);
            app.nLabel.Text = 'n';
            app.nSimpson38 = uieditfield(app.Simpson38Tab, 'text');
            app.ResultadoSimpson38 = uilabel(app.Simpson38Tab);
            app.ResultadoSimpson38.Text = 'Resultado';
            app.RungeKutta3Tab = uitab(app.TabGroup);
            app.RungeKutta3Tab.Title = 'Runge Kutta 3';
            app.GraficaRungeKutta3 = uiaxes(app.RungeKutta3Tab);
            title(app.GraficaRungeKutta3, 'Runge Kutta 3')
            xlabel(app.GraficaRungeKutta3, 'X')
            ylabel(app.GraficaRungeKutta3, 'Y')
            zlabel(app.GraficaRungeKutta3, 'Z')
            app.TableRungeKutta3 = uitable(app.RungeKutta3Tab);
            app.TableRungeKutta3.ColumnName = {'i'; 'k1'; 'xk2'; 'yk2'; 'k2'; 'xk3'; 'yk3'; 'k3'; 'yi + 1'};
            app.TableRungeKutta3.RowName = {};
            app.CalcularRungeKutta3 = uibutton(app.RungeKutta3Tab, 'push');
            app.CalcularRungeKutta3.ButtonPushedFcn = createCallbackFcn(app, @CalcularRungeKutta3ButtonPushed, true);
            app.CalcularRungeKutta3.Text = 'Calcular';
            app.FuncionEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.FuncionRungeKutta3 = uieditfield(app.RungeKutta3Tab, 'text');
            app.yEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.yEditFieldLabel.Text = 'y';
            app.yRungeKutta3 = uieditfield(app.RungeKutta3Tab, 'text');
            app.nEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.nEditFieldLabel.Text = 'n';
            app.nRungeKutta3 = uieditfield(app.RungeKutta3Tab, 'text');
            app.hEditField_2Label = uilabel(app.RungeKutta3Tab);
            app.hEditField_2Label.Text = 'h';
            app.hRungeKutta3 = uieditfield(app.RungeKutta3Tab, 'text');
            app.xEditFieldLabel = uilabel(app.RungeKutta3Tab);
            app.xEditFieldLabel.Text = 'x';
            app.xRungeKutta3 = uieditfield(app.RungeKutta3Tab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end